classdef COVID_19_Viz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationUIFigure  matlab.ui.Figure
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data
        world
        start_date
        end_date
        number_days
        avg_days = 1
        current_region
        is_cumulative = true
        wants_cases = true
        wants_deaths = false
    end
    
    
    methods (Access = private)
        
        function plot_data(app, region)
            app.current_region = region;
            [cases, deaths] = region.getData(app.is_cumulative);
            
            x = linspace(app.start_date, app.end_date, app.number_days);
            cla(app.UIAxes, "reset");
            grid(app.UIAxes,'on');
            
            if app.is_cumulative
                name = "Cumulative Number of ";
            else
                name = "Daily Number of ";
            end
            
            avg = "";
            
            if app.avg_days ~= 1
                cases = movmean(cases, [app.avg_days-1, 0]);
                deaths = movmean(deaths, [app.avg_days-1, 0]);
                avg = sprintf(" (%d-day mean)", app.avg_days);
            end
            
            if app.wants_cases
                name = strcat(name, "Cases ");
                bar(app.UIAxes, x, cases, "stacked", "blue");
                hold(app.UIAxes,'on');
            end
            
            if app.wants_deaths
                if app.wants_cases
                    name = strcat(name, "and ");
                    yyaxis(app.UIAxes, "right");
                end
                
                name = strcat(name, "Deaths ");
                plot(app.UIAxes, x, deaths, 'Color', [1 0 0]);
            end
            
            if app.wants_cases && app.wants_deaths
                app.UIAxes.YAxis(1).Exponent = 0;
                app.UIAxes.YAxis(2).Exponent = 0;
                app.UIAxes.YAxis(1).TickLabelFormat = "%d";
                app.UIAxes.YAxis(2).TickLabelFormat = "%d";
            else
                app.UIAxes.YAxis.Exponent = 0;
                app.UIAxes.YAxis.TickLabelFormat = "%d";
            end
            
            name = strcat(name, sprintf("in %s", app.current_region.getName()));
            name = strcat(name, avg);
            title(app.UIAxes, name);
            datetick(app.UIAxes, 'x','mm/dd','keeplimits');
            disableDefaultInteractivity(app.UIAxes);
            hold(app.UIAxes,'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.data = load('covid_data.mat').covid_data;
            data_size = size(app.data);
            days = data_size(1, 2) - 2;
            
            app.start_date = datenum(app.data{1, 3});
            app.end_date = datenum(app.data{1, end});
            app.number_days = days;
            
            %Let's make objects
            app.world = Region("the World", days);
            
            for k = 2:data_size(1, 1)
                if isempty(app.data{k, 2})
                    name = app.data{k, 1};
                    country = Region(name, days);
                    app.world.addRegion(country);
                    handler = country;
                else
                    name = app.data{k, 2};
                    state = Region(name, days);
                    country.addRegion(state);
                    handler = state;
                end
                
                for m = 3:days
                    stats = app.data{k, m};
                    handler.setStats(m, stats(1,1), stats(1,2));
                end
            end
            
            app.world.calcStats();
            
            %Fill and select the list boxes
            names = [{'Global'} app.world.getSubregionNames()];
            app.CountryListBox.Items = names;
            app.CountryListBox.ItemsData = 1:length(names);
            app.CountryListBox.Value = 1;
            app.StateorRegionListBox.Items = {'All'};
            app.StateorRegionListBox.ItemsData = 1;
            app.StateorRegionListBox.Value = 1;
            
            %plot the data
            app.plot_data(app.world);
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            index = app.CountryListBox.Value;
            names = {'All'};
            
            %Fill the other list box
            if index ~= 1 %Then the user selected a country
                country = app.world.getSubregion(index - 1);
                names = [names country.getSubregionNames()];
            end
            
            app.StateorRegionListBox.Items = names;
            app.StateorRegionListBox.ItemsData = 1:length(names);
            app.StateorRegionListBox.Value = 1;
            
            if index == 1 % It's the world
                region = app.world;
            else % It's the country
                region = app.world.getSubregion(index - 1);
            end
            
            app.plot_data(region);
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            state = app.StateorRegionListBox.Value;
            country = app.CountryListBox.Value;
            
            if country == 1 % It's the world
                region = app.world;
            elseif state == 1 % It's the country
                region = app.world.getSubregion(country - 1);
            else
                region = app.world.getSubregion(country - 1);
                region = region.getSubregion(state - 1);
            end
            
            app.plot_data(region);
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatatoPlotButtonGroup.SelectedObject;
            
            switch get(selectedButton, 'tag')
                case 'Cases'
                    app.wants_cases = true;
                    app.wants_deaths = false;
                case 'Deaths'
                    app.wants_cases = false;
                    app.wants_deaths = true;
                otherwise
                    app.wants_cases = true;
                    app.wants_deaths = true;
            end
            
            app.plot_data(app.current_region);
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionButtonGroup.SelectedObject;
            
            switch get(selectedButton, 'tag')
                case 'Cumulative'
                    app.is_cumulative = true;
                otherwise
                    app.is_cumulative = false;
            end
            
            app.plot_data(app.current_region);
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            app.avg_days = round(app.AveragedofdaysSlider.Value);
            app.plot_data(app.current_region);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualizationUIFigure.Name = 'COVID-19 Data Visualization (Data source: Johns Hopkins University)';
            app.CountryListBoxLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateorRegionListBoxLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.StateorRegionListBoxLabel.Text = {'State or '; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.StateorRegionListBox.Items = {};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.Value = {};
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Tag = 'Cases';
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Tag = 'Deaths';
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Tag = 'Both';
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Tag = 'Cumulative';
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Tag = 'Daily';
            app.DailyButton.Text = 'Daily';
            app.AveragedofdaysSliderLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.COVID19DataVisualizationUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedofdaysSlider.Value = 1;
            app.UIAxes = uiaxes(app.COVID19DataVisualizationUIFigure);
            % Show the figure after all components are created
            app.COVID19DataVisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID_19_Viz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationUIFigure)
        end
    end
end